classdef ez_roi_refine < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EZROIRefinementUIFigure         matlab.ui.Figure
        StopButton                      matlab.ui.control.Button
        SettingsHelpButton              matlab.ui.control.Button
        ExportDataButton                matlab.ui.control.Button
        HelpButton                      matlab.ui.control.Button
        RunAutoExclusionButton          matlab.ui.control.Button
        SettingsPanel                   matlab.ui.container.Panel
        FLSNCheckBox                    matlab.ui.control.CheckBox
        KurtosisCheckBox                matlab.ui.control.CheckBox
        SkewnessCheckBox                matlab.ui.control.CheckBox
        AreaCheckBox                    matlab.ui.control.CheckBox
        MeanWidthCheckBox               matlab.ui.control.CheckBox
        SaturatedFramesCheckBox         matlab.ui.control.CheckBox
        OblongnessCheckBox              matlab.ui.control.CheckBox
        RoundnessCheckBox               matlab.ui.control.CheckBox
        BaselineStabilityCheckBox       matlab.ui.control.CheckBox
        ActivityZscoreCheckBox          matlab.ui.control.CheckBox
        FramesLabel_2                   matlab.ui.control.Label
        FramesLabel                     matlab.ui.control.Label
        SkewKurtosisDataHelpButton      matlab.ui.control.Button
        KurtosisHelpButton              matlab.ui.control.Button
        SkewnessHelpButton              matlab.ui.control.Button
        AreaHelpButton                  matlab.ui.control.Button
        MeanWidthHelpButton             matlab.ui.control.Button
        BorderlineAllowanceHelpButton   matlab.ui.control.Button
        BorderlineHelpButton            matlab.ui.control.Button
        SaturatedFramesHelpButton       matlab.ui.control.Button
        OblongnessHelpButton            matlab.ui.control.Button
        RoundnessHelpButton             matlab.ui.control.Button
        BaselineStabilityHelpButton     matlab.ui.control.Button
        BaselineLocationHelpButton      matlab.ui.control.Button
        ActivityZscoreHelpButton        matlab.ui.control.Button
        WindowLabel                     matlab.ui.control.Label
        ValueLabel                      matlab.ui.control.Label
        ActivityZscoreWindowEditField   matlab.ui.control.NumericEditField
        BaselineLocationWindowEditField  matlab.ui.control.NumericEditField
        SaturatedFramesDropDown         matlab.ui.control.DropDown
        SaturatedFramesEditField_2      matlab.ui.control.NumericEditField
        BorderlineLabel                 matlab.ui.control.Label
        MaxLabel                        matlab.ui.control.Label
        MeanWidthMaxEditField           matlab.ui.control.NumericEditField
        AreaMaxEditField                matlab.ui.control.NumericEditField
        SkewnessMinEditField            matlab.ui.control.NumericEditField
        KurtosisMaxEditField            matlab.ui.control.NumericEditField
        SkewKurtosisDataDropDown        matlab.ui.control.DropDown
        SkewKurtosisDataLabel           matlab.ui.control.Label
        MinLabel                        matlab.ui.control.Label
        KurtosisMinEditField            matlab.ui.control.NumericEditField
        SkewnessMaxEditField            matlab.ui.control.NumericEditField
        AreaMinEditField                matlab.ui.control.NumericEditField
        MeanWidthMinEditField           matlab.ui.control.NumericEditField
        BorderlineAllowanceEditField    matlab.ui.control.NumericEditField
        BorderlineEditField             matlab.ui.control.NumericEditField
        OblongnessEditField_2           matlab.ui.control.NumericEditField
        RoundnessEditField_2            matlab.ui.control.NumericEditField
        BaselineStabilityEditField_2    matlab.ui.control.NumericEditField
        BaselineLocationValueEditField  matlab.ui.control.NumericEditField
        XLSXCheckBox                    matlab.ui.control.CheckBox
        CSVCheckBox                     matlab.ui.control.CheckBox
        MATCheckBox                     matlab.ui.control.CheckBox
        ExportFormatLabel               matlab.ui.control.Label
        BorderlineAllowanceLabel        matlab.ui.control.Label
        BaselineLocationLabel           matlab.ui.control.Label
        ActivityZscoreValueEditField    matlab.ui.control.NumericEditField
        LoadSaveHelpButton              matlab.ui.control.Button
        LoadSettingsButton              matlab.ui.control.Button
        SaveSettingsButton              matlab.ui.control.Button
        InfoPanel                       matlab.ui.container.Panel
        SaturatedFramesEditField        matlab.ui.control.EditField
        KurtosisDeconvEditField         matlab.ui.control.EditField
        KurtosisRawEditField            matlab.ui.control.EditField
        SkewnessDeconvEditField         matlab.ui.control.EditField
        SkewnessRawEditField            matlab.ui.control.EditField
        AreaEditField                   matlab.ui.control.EditField
        MeanWidthEditField              matlab.ui.control.EditField
        OblongnessEditField             matlab.ui.control.EditField
        RoundnessEditField              matlab.ui.control.EditField
        BaselineStabilityEditField      matlab.ui.control.EditField
        StatusEditField                 matlab.ui.control.EditField
        DeconvolvedLabel                matlab.ui.control.Label
        InferredLabel                   matlab.ui.control.Label
        RawLabel                        matlab.ui.control.Label
        IncludeExcludeROIHelpButton     matlab.ui.control.Button
        ExcludeROIButton                matlab.ui.control.Button
        IncludeROIButton                matlab.ui.control.Button
        SaturatedFramesLabel            matlab.ui.control.Label
        KurtosisDeconvLabel             matlab.ui.control.Label
        KurtosisRawLabel                matlab.ui.control.Label
        SkewnessDeconvLabel             matlab.ui.control.Label
        SkewnessRawLabel                matlab.ui.control.Label
        AreaLabel                       matlab.ui.control.Label
        MeanWidthLabel                  matlab.ui.control.Label
        OblongnessLabel                 matlab.ui.control.Label
        RoundnessLabel                  matlab.ui.control.Label
        BaselineStabilityLabel          matlab.ui.control.Label
        DeconvolvedUIAxes               matlab.ui.control.UIAxes
        InferredUIAxes                  matlab.ui.control.UIAxes
        RawUIAxes                       matlab.ui.control.UIAxes
        SingleROIUIAxes                 matlab.ui.control.UIAxes
        WholeFieldUIAxes                matlab.ui.control.UIAxes
        ROIsPanel                       matlab.ui.container.Panel
        NextButton                      matlab.ui.control.Button
        PreviousButton                  matlab.ui.control.Button
        ROIListBox                      matlab.ui.control.ListBox
        LoadDataButton                  matlab.ui.control.Button
        ROIsLabel                       matlab.ui.control.Label
        SettingsLabel                   matlab.ui.control.Label
        ROIRefinementLabel              matlab.ui.control.Label
    end

    
    properties (Access = private)
        roiref  % internal storage of GUI settings
        border  % internal storage of borderline values
        roidata % loaded ROI data
    end
    
    methods (Access = private)
        
        function parse_roiref(app)
            app.roiref.zscore_check = app.ActivityZscoreCheckBox.Value;
            app.roiref.zscore = app.ActivityZscoreValueEditField.Value;
            app.roiref.zscore_window = app.ActivityZscoreWindowEditField.Value;
            
            app.roiref.baseline_location = app.BaselineLocationValueEditField.Value;
            app.roiref.baseline_window = app.BaselineLocationWindowEditField.Value;
            
            app.roiref.baseline_stability_check = app.BaselineStabilityCheckBox.Value;
            app.roiref.baseline_stability = app.BaselineStabilityEditField_2.Value;
            
            app.roiref.roundness_check = app.RoundnessCheckBox.Value;
            app.roiref.roundness = app.RoundnessEditField_2.Value;
            
            app.roiref.oblongness_check = app.OblongnessCheckBox.Value;
            app.roiref.oblongness = app.OblongnessEditField_2.Value;
            
            app.roiref.saturated_check = app.SaturatedFramesCheckBox.Value;
            app.roiref.saturated = app.SaturatedFramesEditField_2.Value;
            app.roiref.saturated_style = app.SaturatedFramesDropDown.Value;
            
            app.roiref.borderline = app.BorderlineEditField.Value;
            app.roiref.borderline_allow = app.BorderlineAllowanceEditField.Value;
            
            app.roiref.width_check = app.MeanWidthCheckBox.Value;
            app.roiref.width_min = app.MeanWidthMinEditField.Value;
            app.roiref.width_max = app.MeanWidthMaxEditField.Value;
            
            app.roiref.area_check = app.AreaCheckBox.Value;
            app.roiref.area_min = app.AreaMinEditField.Value;
            app.roiref.area_max = app.AreaMaxEditField.Value;
            
            app.roiref.skewness_check = app.SkewnessCheckBox.Value;
            app.roiref.skewness_min = app.SkewnessMinEditField.Value;
            app.roiref.skewness_max = app.SkewnessMaxEditField.Value;
            
            app.roiref.kurtosis_check = app.KurtosisCheckBox.Value;
            app.roiref.kurtosis_min = app.KurtosisMinEditField.Value;
            app.roiref.kurtosis_max = app.KurtosisMaxEditField.Value;
            
            app.roiref.skew_kurtosis_style = app.SkewKurtosisDataDropDown.Value;
            
            app.roiref.export_mat = app.MATCheckBox.Value;
            app.roiref.export_csv = app.CSVCheckBox.Value;
            app.roiref.export_xlsx = app.XLSXCheckBox.Value;
            
            % Convert saturation value into frames (if not already)
            if strcmp(app.roiref.saturated_style, 'Frames')
                app.roiref.saturated_frames = app.roiref.saturated;
            elseif strcmp(app.roiref.saturated_frames_style, '%')
                app.roiref.saturated_frames = floor(app.roiref.saturated / 100 * size(app.roidata.F_raw, 2));
            end
        end
        
        function write_roiref(app)
            app.ActivityZscoreCheckBox.Value = app.roiref.zscore_check;
            app.ActivityZscoreValueEditField.Value= app.roiref.zscore;
            app.ActivityZscoreWindowEditField.Value = app.roiref.zscore_window;
            
            app.BaselineLocationValueEditField.Value = app.roiref.baseline_location;
            app.BaselineLocationWindowEditField.Value = app.roiref.baseline_window;
            
            app.BaselineStabilityCheckBox.Value = app.roiref.baseline_stability_check;
            app.BaselineStabilityEditField_2.Value = app.roiref.baseline_stability;
            
            app.RoundnessCheckBox.Value = app.roiref.roundness_check;
            app.RoundnessEditField_2.Value = app.roiref.roundness;
            
            app.OblongnessCheckBox.Value = app.roiref.oblongness_check;
            app.OblongnessEditField_2.Value = app.roiref.oblongness;
            
            app.SaturatedFramesCheckBox.Value = app.roiref.saturated_check;
            app.SaturatedFramesEditField_2.Value = app.roiref.saturated;
            app.SaturatedFramesDropDown.Value = app.roiref.saturated_style;
            
            app.BorderlineEditField.Value = app.roiref.borderline;
            app.BorderlineAllowanceEditField.Value = app.roiref.borderline_allow;
            
            app.MeanWidthCheckBox.Value = app.roiref.width_check;
            app.MeanWidthMinEditField.Value = app.roiref.width_min;
            app.MeanWidthMaxEditField.Value = app.roiref.width_max;
            
            app.AreaCheckBox.Value = app.roiref.area_check;
            app.AreaMinEditField.Value = app.roiref.area_min;
            app.AreaMaxEditField.Value = app.roiref.area_max;
            
            app.SkewnessCheckBox.Value = app.roiref.skewness_check;
            app.SkewnessMinEditField.Value = app.roiref.skewness_min;
            app.SkewnessMaxEditField.Value = app.roiref.skewness_max;
            
            app.KurtosisCheckBox.Value = app.roiref.kurtosis_check;
            app.KurtosisMinEditField.Value = app.roiref.kurtosis_min;
            app.KurtosisMaxEditField.Value = app.roiref.kurtosis_max;
            
            app.SkewKurtosisDataDropDown.Value = app.roiref.skew_kurtosis_style;
            
            app.MATCheckBox.Value = app.roiref.export_mat;
            app.CSVCheckBox.Value = app.roiref.export_csv;
            app.XLSXCheckBox.Value = app.roiref.export_xlsx;
        end
        
        function [Z_mod, F_baseline_mean, F_baseline_std, baseline_start_frame] = calc_zscore(~, F, frames)
            [F_baseline_std, baseline_start_frame] = min(movstd(F, frames, 0, 2, 'Endpoints', 'discard'), [], 2);
            F_baseline_mean = mean(F(:, baseline_start_frame:baseline_start_frame+frames-1), 2);
            Z_mod = (F - F_baseline_mean) ./ F_baseline_std;
        end
        
        function calc_roidata(app)
            parse_roiref(app)
            calc_border(app)
            
            % Check if baseline stability frame window is valid
            baseline_loc = app.roiref.baseline_location / 100;
            if app.roidata.num_of_frames * baseline_loc < app.roiref.baseline_window
                msgbox('Baseline window has too many frames to fit in Baseline Location. Setting baseline window to maximum possible value as fallback.', 'Warning');
                app.roiref.baseline_window = floor(app.roidata.num_of_frames * baseline_loc);
                app.BaselineLocationWindowEditField.Value = app.roiref.baseline_window; drawnow
            end
            
            % Calculate baseline stability - first vs last
            app.roidata.Z_mod = calc_zscore(app, app.roidata.F_raw, app.roiref.baseline_window); % calculate Z_mod
            
            first_frame_start = 1;
            first_frame_end = floor(baseline_loc * app.roidata.num_of_frames);
            last_frame_start = app.roidata.num_of_frames - first_frame_end + 1;
            last_frame_end = app.roidata.num_of_frames;
            
            [~, first_baseline, ~, ~] = calc_zscore(app, app.roidata.Z_mod(:, first_frame_start:first_frame_end), app.roiref.baseline_window);
            [~, last_baseline, ~, ~] = calc_zscore(app, app.roidata.Z_mod(:, last_frame_start:last_frame_end), app.roiref.baseline_window);
            app.roidata.baseline_stability = abs(first_baseline'-last_baseline');
            
            % Calculate significant activity
            significant_frames = app.roidata.Z_mod > app.roiref.zscore;
            app.roidata.active_ROI = max(movsum(significant_frames, app.roiref.zscore_window, 2, 'Endpoints', 'discard'), [], 2) == app.roiref.zscore_window;
            
            % Calculate saturated frames
            saturated_value = max(app.roidata.F_raw, [], 1);
            app.roidata.saturated_frames = zeros(1, app.roidata.num_of_roi);
            for i = 1:app.roidata.num_of_roi
                app.roidata.saturated_frames(i) = numel(find(app.roidata.F_raw(i, :) == saturated_value(i)));
                if app.roidata.saturated_frames(i) == 1 % if only one frame is at max value, assume it is not saturated
                    app.roidata.saturated_frames(i) = 0;
                end
            end
            
            % Binarize image, extract stats
            app.roidata.area = zeros(1, app.roidata.num_of_roi);
            app.roidata.roundness = zeros(1, app.roidata.num_of_roi);
            app.roidata.width = zeros(1, app.roidata.num_of_roi);
            app.roidata.oblongness = zeros(1, app.roidata.num_of_roi);
            for i = 1:app.roidata.num_of_roi
                single_ROI = full(reshape(app.roidata.spatial_comp(:, i), app.roidata.map_size(1), app.roidata.map_size(2)));
                
                single_ROI = imbinarize(single_ROI, 0); % binary image with logical values
                image_stats = regionprops(single_ROI, 'Area', 'Perimeter');
                if length(image_stats) > 1 % check if ROI has discontiguous regions
                    [~, region_ind] = max([image_stats.Area]);
                    image_stats = image_stats(region_ind); % take only the biggest region
                end
                app.roidata.roundness(i) = (4 * pi * image_stats.Area) / (image_stats.Perimeter ^ 2);
                
                single_ROI = double(single_ROI); % convert to numerical values
                image_stats = regionprops(single_ROI, 'Area', 'MajorAxisLength', 'MinorAxisLength');
                app.roidata.area(i) = image_stats.Area;
                app.roidata.width(i) = mean([image_stats.MajorAxisLength image_stats.MinorAxisLength]);
                app.roidata.oblongness(i) = image_stats.MajorAxisLength / image_stats.MinorAxisLength;
            end
            
            app.roidata.kurtosis_raw = kurtosis(app.roidata.F_raw');
            app.roidata.kurtosis_deconv = kurtosis(app.roidata.S_deconv');
            
            app.roidata.skewness_raw = skewness(app.roidata.F_raw');
            app.roidata.skewness_deconv = skewness(app.roidata.S_deconv');
        end
        
        function view_roi(app)
            parse_roiref(app)
            calc_border(app)
            
            [~, cur_roi] = ismember(app.ROIListBox.Value, app.ROIListBox.Items);
            
            % Plot traces
            plot(app.RawUIAxes, app.roidata.Z_mod(cur_roi, :))
            % Add active trace threshold
            if app.roiref.zscore_check
                hold(app.RawUIAxes, 'on')
                threshold_line = zeros(1, size(app.roidata.Z_mod, 2)) + app.roiref.zscore;
                plot(app.RawUIAxes, threshold_line);
                hold(app.RawUIAxes, 'off')
            end
            
            plot(app.InferredUIAxes, app.roidata.F_inferred(cur_roi, :))
            
            plot(app.DeconvolvedUIAxes, app.roidata.S_deconv(cur_roi, :))
            
            % Display whole field map
            map_size = size(app.roidata.Cn);
            abs_whole_field_size = size(app.roidata.ROI_map); % getframe results a larger image on high-DPI systems, so here we get the size of it in absolute number of pixels
            image(app.WholeFieldUIAxes, app.roidata.ROI_map)
            hold(app.WholeFieldUIAxes, 'on')
            
            single_ROI = full(reshape(app.roidata.spatial_comp(:, cur_roi), map_size(1), map_size(2)));
            single_ROI = imresize(single_ROI, [abs_whole_field_size(1), abs_whole_field_size(2)], 'nearest');
            single_ROI = medfilt2(single_ROI, [3, 3]);
            single_ROI = single_ROI(:);
            [temp, ind] = sort(single_ROI(:) .^ 2, 'ascend');
            temp =  cumsum(temp);
            ff = find(temp > (1 - 0.95) * temp(end), 1, 'first');
            if ~isempty(ff)
                [~, ww] = contour(app.WholeFieldUIAxes, reshape(single_ROI, abs_whole_field_size(1), abs_whole_field_size(2)), [0, 0] + single_ROI(ind(ff)), 'LineColor', 'k');
                ww.LineWidth = 2;
            end
            hold(app.WholeFieldUIAxes, 'off')
            axis(app.WholeFieldUIAxes, 'image') % make sure image fill the area
            
            % Display selected ROI
            single_ROI = reshape(app.roidata.spatial_comp(:, cur_roi), map_size(1), map_size(2));
            i = cur_roi;
            int_x = app.roidata.int_x;
            int_y = app.roidata.int_y;
            sx = app.roidata.sx;
            cm = app.roidata.cm;
            d1 = map_size(1);
            d2 = map_size(2);
            
            int_x(i, :) = round(cm(i, 1)) + (-(sx-1):sx);
            if int_x(i, 1) < 1
                int_x(i, :) = int_x(i, :) + 1 - int_x(i, 1);
            end
            if int_x(i, end) > d1
                int_x(i, :) = int_x(i, :) - (int_x(i, end)-d1);
            end
            int_y(i, :) = round(cm(i, 2)) + (-(sx-1):sx);
            if int_y(i, 1) < 1
                int_y(i, :) = int_y(i, :) + 1 - int_y(i, 1);
            end
            if int_y(i, end) > d2
                int_y(i, :) = int_y(i, :) - (int_y(i, end)-d2);
            end
            single_ROI = single_ROI(int_x(i, :), int_y(i, :));
            imagesc(app.SingleROIUIAxes, int_x(i, :), int_y(i, :), single_ROI)
            axis(app.SingleROIUIAxes, 'image') % make sure image fill the area
            
            % Display exclusion status
            cur_roi_name = app.ROIListBox.Items(cur_roi);
            if contains(cur_roi_name, 'X') % checks if marked excluded
                app.StatusEditField.Value = ['ROI #' num2str(cur_roi) ' - excluded'];
                app.StatusEditField.FontColor = [1 1 1];
                app.StatusEditField.BackgroundColor = [0.6350, 0.0780, 0.1840];
            elseif contains(cur_roi_name, 'B')  % checks if marked borderline
                app.StatusEditField.Value = ['ROI #' num2str(cur_roi) ' - borderline'];
                app.StatusEditField.FontColor = [0 0 0];
                app.StatusEditField.BackgroundColor = [1 1 0.0667];
            else
                app.StatusEditField.Value = ['ROI #' num2str(cur_roi) ' - included'];
                app.StatusEditField.FontColor = [0 0 0];
                app.StatusEditField.BackgroundColor = [1 1 1];
            end
            
            % Display values
            app.BaselineStabilityEditField.Value = num2str(app.roidata.baseline_stability(cur_roi), '%11.4g');
            app.RoundnessEditField.Value = num2str(app.roidata.roundness(cur_roi), '%11.4g');
            app.OblongnessEditField.Value = num2str(app.roidata.oblongness(cur_roi), '%11.4g');
            app.MeanWidthEditField.Value = num2str(app.roidata.width(cur_roi), '%11.4g');
            app.AreaEditField.Value = num2str(app.roidata.area(cur_roi), '%11.4g');
            app.SkewnessRawEditField.Value = num2str(app.roidata.skewness_raw(cur_roi), '%11.4g');
            app.SkewnessDeconvEditField.Value = num2str(app.roidata.skewness_deconv(cur_roi), '%11.4g');
            app.KurtosisRawEditField.Value = num2str(app.roidata.kurtosis_raw(cur_roi), '%11.4g');
            app.KurtosisDeconvEditField.Value = num2str(app.roidata.kurtosis_deconv(cur_roi), '%11.4g');
            app.SaturatedFramesEditField.Value = num2str(app.roidata.saturated_frames(cur_roi), '%11.4g');
            
            % Set exclusionary markings
            
            if ~app.roiref.baseline_stability_check || app.roidata.baseline_stability(cur_roi) <= app.roiref.baseline_stability
                app.BaselineStabilityEditField.FontColor = [0 0 0];
                app.BaselineStabilityEditField.BackgroundColor = [1 1 1];
            elseif app.roidata.baseline_stability(cur_roi) <= app.border.baseline_stability
                app.BaselineStabilityEditField.FontColor = [0 0 0];
                app.BaselineStabilityEditField.BackgroundColor = [1 1 0.0667];
            else
                app.BaselineStabilityEditField.FontColor = [1 1 1];
                app.BaselineStabilityEditField.BackgroundColor = [0.6350, 0.0780, 0.1840];
            end
            
            if ~app.roiref.roundness_check || app.roidata.roundness(cur_roi) >= app.roiref.roundness
                app.RoundnessEditField.FontColor = [0 0 0];
                app.RoundnessEditField.BackgroundColor = [1 1 1];
            elseif app.roidata.roundness(cur_roi) >= app.border.roundness
                app.RoundnessEditField.FontColor = [0 0 0];
                app.RoundnessEditField.BackgroundColor = [1 1 0.0667];
            else
                app.RoundnessEditField.FontColor = [1 1 1];
                app.RoundnessEditField.BackgroundColor = [0.6350, 0.0780, 0.1840];
            end
            
            if ~app.roiref.oblongness_check || app.roidata.oblongness(cur_roi) <= app.roiref.oblongness
                app.OblongnessEditField.FontColor = [0 0 0];
                app.OblongnessEditField.BackgroundColor = [1 1 1];
            elseif app.roidata.oblongness(cur_roi) <= app.border.oblongness
                app.OblongnessEditField.FontColor = [0 0 0];
                app.OblongnessEditField.BackgroundColor = [1 1 0.0667];
            else
                app.OblongnessEditField.FontColor = [1 1 1];
                app.OblongnessEditField.BackgroundColor = [0.6350, 0.0780, 0.1840];
            end
            
            if ~app.roiref.width_check || (app.roidata.width(cur_roi) >= app.roiref.width_min && app.roidata.width(cur_roi) <= app.roiref.width_max)
                app.MeanWidthEditField.FontColor = [0 0 0];
                app.MeanWidthEditField.BackgroundColor = [1 1 1];
            elseif app.roidata.width(cur_roi) >= app.border.width_min && app.roidata.width(cur_roi) <= app.border.width_max
                app.MeanWidthEditField.FontColor = [0 0 0];
                app.MeanWidthEditField.BackgroundColor = [1 1 0.0667];
            else
                app.MeanWidthEditField.FontColor = [1 1 1];
                app.MeanWidthEditField.BackgroundColor = [0.6350, 0.0780, 0.1840];
            end
            
            if ~app.roiref.area_check || (app.roidata.area(cur_roi) >= app.roiref.area_min && app.roidata.area(cur_roi) <= app.roiref.area_max)
                app.AreaEditField.FontColor = [0 0 0];
                app.AreaEditField.BackgroundColor = [1 1 1];
            elseif app.roidata.area(cur_roi) >= app.border.area_min && app.roidata.area(cur_roi) <= app.border.area_max
                app.AreaEditField.FontColor = [0 0 0];
                app.AreaEditField.BackgroundColor = [1 1 0.0667];
            else
                app.AreaEditField.FontColor = [1 1 1];
                app.AreaEditField.BackgroundColor = [0.6350, 0.0780, 0.1840];
            end
            
            if strcmp(app.roiref.skew_kurtosis_style, 'Raw')
                app.SkewnessDeconvEditField.FontColor = [0 0 0];
                app.SkewnessDeconvEditField.BackgroundColor = [1 1 1];
                app.KurtosisDeconvEditField.FontColor = [0 0 0];
                app.KurtosisDeconvEditField.BackgroundColor = [1 1 1];
                
                if ~app.roiref.skewness_check || (app.roidata.skewness_raw(cur_roi) >= app.roiref.skewness_min && app.roidata.skewness_raw(cur_roi) <= app.roiref.skewness_max)
                    app.SkewnessRawEditField.FontColor = [0 0 0];
                    app.SkewnessRawEditField.BackgroundColor = [1 1 1];
                elseif app.roidata.skewness_raw(cur_roi) >= app.border.skewness_min && app.roidata.skewness_raw(cur_roi) <= app.border.skewness_max
                    app.SkewnessRawEditField.FontColor = [0 0 0];
                    app.SkewnessRawEditField.BackgroundColor = [1 1 0.0667];
                else
                    app.SkewnessRawEditField.FontColor = [1 1 1];
                    app.SkewnessRawEditField.BackgroundColor = [0.6350, 0.0780, 0.1840];
                end
                
                if ~app.roiref.kurtosis_check || (app.roidata.kurtosis_raw(cur_roi) >= app.roiref.kurtosis_min && app.roidata.kurtosis_raw(cur_roi) <= app.roiref.kurtosis_max)
                    app.KurtosisRawEditField.FontColor = [0 0 0];
                    app.KurtosisRawEditField.BackgroundColor = [1 1 1];
                elseif app.roidata.kurtosis_raw(cur_roi) >= app.border.kurtosis_min && app.roidata.kurtosis_raw(cur_roi) <= app.border.kurtosis_max
                    app.KurtosisRawEditField.FontColor = [0 0 0];
                    app.KurtosisRawEditField.BackgroundColor = [1 1 0.0667];
                else
                    app.KurtosisRawEditField.FontColor = [1 1 1];
                    app.KurtosisRawEditField.BackgroundColor = [0.6350, 0.0780, 0.1840];
                end
            elseif strcmp(app.roiref.skew_kurtosis_style, 'Deconvolved')
                app.SkewnessRawEditField.FontColor = [0 0 0];
                app.SkewnessRawEditField.BackgroundColor = [1 1 1];
                app.KurtosisRawEditField.FontColor = [0 0 0];
                app.KurtosisRawEditField.BackgroundColor = [1 1 1];
                
                if ~app.roiref.skewness_check || (app.roidata.skewness_deconv(cur_roi) >= app.roiref.skewness_min && app.roidata.skewness_deconv(cur_roi) <= app.roiref.skewness_max)
                    app.SkewnessDeconvEditField.FontColor = [0 0 0];
                    app.SkewnessDeconvEditField.BackgroundColor = [1 1 1];
                elseif app.roidata.skewness_deconv(cur_roi) >= app.border.skewness_min && app.roidata.skewness_deconv(cur_roi) <= app.border.skewness_max
                    app.SkewnessDeconvEditField.FontColor = [0 0 0];
                    app.SkewnessDeconvEditField.BackgroundColor = [1 1 0.0667];
                else
                    app.SkewnessDeconvEditField.FontColor = [1 1 1];
                    app.SkewnessDeconvEditField.BackgroundColor = [0.6350, 0.0780, 0.1840];
                end
                
                if ~app.roiref.kurtosis_check || (app.roidata.kurtosis_deconv(cur_roi) >= app.roiref.kurtosis_min && app.roidata.kurtosis_deconv(cur_roi) <= app.roiref.kurtosis_max)
                    app.KurtosisDeconvEditField.FontColor = [0 0 0];
                    app.KurtosisDeconvEditField.BackgroundColor = [1 1 1];
                elseif app.roidata.kurtosis_deconv(cur_roi) >= app.border.kurtosis_min && app.roidata.kurtosis_deconv(cur_roi) <= app.border.kurtosis_max
                    app.KurtosisDeconvEditField.FontColor = [0 0 0];
                    app.KurtosisDeconvEditField.BackgroundColor = [1 1 0.0667];
                else
                    app.KurtosisDeconvEditField.FontColor = [1 1 1];
                    app.KurtosisDeconvEditField.BackgroundColor = [0.6350, 0.0780, 0.1840];
                end
            end
            
            if ~app.roiref.saturated_check || app.roidata.saturated_frames(cur_roi) <= app.roiref.saturated_frames
                app.SaturatedFramesEditField.FontColor = [0 0 0];
                app.SaturatedFramesEditField.BackgroundColor = [1 1 1];
            elseif app.roidata.saturated_frames(cur_roi) <= app.border.saturated_frames
                app.SaturatedFramesEditField.FontColor = [0 0 0];
                app.SaturatedFramesEditField.BackgroundColor = [1 1 0.0667];
            else
                app.SaturatedFramesEditField.FontColor = [1 1 1];
                app.SaturatedFramesEditField.BackgroundColor = [0.6350, 0.0780, 0.1840];
            end
            
            drawnow;
        end
        
        function calc_border(app)
            border_percent = app.roiref.borderline / 100;
            
            app.border.zscore = app.roiref.zscore * (1 - border_percent);
            app.border.baseline_stability = app.roiref.baseline_stability * (1 + border_percent);
            app.border.roundness = app.roiref.roundness * (1 - border_percent);
            app.border.oblongness = app.roiref.oblongness * (1 + border_percent);
            app.border.saturated_frames = app.roiref.saturated_frames * (1 + border_percent);
            app.border.width_min = app.roiref.width_min * (1 - border_percent);
            app.border.width_max = app.roiref.width_max * (1 + border_percent);
            app.border.area_min = app.roiref.area_min * (1 - border_percent);
            app.border.area_max = app.roiref.area_max * (1 + border_percent);
            app.border.skewness_min = app.roiref.skewness_min * (1 - border_percent);
            app.border.skewness_max = app.roiref.skewness_max * (1 + border_percent);
            app.border.kurtosis_min = app.roiref.kurtosis_min * (1 - border_percent);
            app.border.kurtosis_max = app.roiref.kurtosis_max * (1 + border_percent);
        end
        
        function previous_roi(app)
            [~, cur_roi] = ismember(app.ROIListBox.Value, app.ROIListBox.Items);
            if cur_roi == 1
                return
            else
                app.ROIListBox.Value = app.ROIListBox.Items(cur_roi - 1); drawnow
            end
            view_roi(app)
        end
        
        function next_roi(app)
            [~, cur_roi] = ismember(app.ROIListBox.Value, app.ROIListBox.Items);
            if cur_roi == length(app.ROIListBox.Items)
                return
            else
                app.ROIListBox.Value = app.ROIListBox.Items(cur_roi + 1); drawnow
            end
            view_roi(app)
        end
        
        function include_roi(app)
            [~, cur_roi] = ismember(app.ROIListBox.Value, app.ROIListBox.Items);
            app.ROIListBox.Items(cur_roi) = {num2str(cur_roi)};
            app.ROIListBox.Value = app.ROIListBox.Items(cur_roi);
            drawnow
            view_roi(app)
        end
        
        function exclude_roi(app)
            [~, cur_roi] = ismember(app.ROIListBox.Value, app.ROIListBox.Items);
            app.ROIListBox.Items(cur_roi) = {[num2str(cur_roi) ' X']};
            app.ROIListBox.Value = app.ROIListBox.Items(cur_roi);
            drawnow
            view_roi(app)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            disableDefaultInteractivity(app.WholeFieldUIAxes)
            disableDefaultInteractivity(app.SingleROIUIAxes)
            disableDefaultInteractivity(app.RawUIAxes)
            disableDefaultInteractivity(app.InferredUIAxes)
            disableDefaultInteractivity(app.DeconvolvedUIAxes)
            
            filepath = fileparts([mfilename('fullpath') '.m']);
            settings_file = fullfile(filepath, 'ez_settings.mat');
            if isfile(settings_file)
                if ismember('roiref_settings', who('-file', settings_file))
                    load(fullfile(filepath, 'ez_settings.mat'), 'roiref_settings')
                    app.roiref = roiref_settings;
                    write_roiref(app)
                end
            end
            
            if ~app.ActivityZscoreCheckBox.Value
                app.ActivityZscoreValueEditField.Enable = 'off';
                app.ActivityZscoreWindowEditField.Enable = 'off';
                app.FramesLabel.Enable = 'off'; drawnow
            end
            
            if ~app.BaselineStabilityCheckBox.Value
                app.BaselineStabilityEditField_2.Enable = 'off'; drawnow
            end
            
            if ~app.RoundnessCheckBox.Value
                app.RoundnessEditField_2.Enable = 'off'; drawnow
            end
            
            if ~app.OblongnessCheckBox.Value
                app.OblongnessEditField_2.Enable = 'off'; drawnow
            end
            
            if ~app.SaturatedFramesCheckBox.Value
                app.SaturatedFramesEditField_2.Enable = 'off';
                app.SaturatedFramesDropDown.Enable = 'off'; drawnow
            end
            
            if ~app.MeanWidthCheckBox.Value
                app.MeanWidthMinEditField.Enable = 'off';
                app.MeanWidthMaxEditField.Enable = 'off'; drawnow
            end
            
            if ~app.AreaCheckBox.Value
                app.AreaMinEditField.Enable = 'off';
                app.AreaMaxEditField.Enable = 'off'; drawnow
            end
            
            if ~app.SkewnessCheckBox.Value
                app.SkewnessMinEditField.Enable = 'off';
                app.SkewnessMaxEditField.Enable = 'off'; drawnow
            end
            
            if ~app.KurtosisCheckBox.Value
                app.KurtosisMinEditField.Enable = 'off';
                app.KurtosisMaxEditField.Enable = 'off'; drawnow
            end
            
            warning_text = '';
            if ~license('test', 'image_toolbox')
                warning_text = [warning_text newline '    Image Processing Toolbox'];
            end
            if ~license('test', 'statistics_toolbox')
                warning_text = [warning_text newline '    Statistics and Machine Learning Toolbox'];
            end
            if ~isempty(warning_text)
                warning_text = ['It seems like you do not have the following MATLAB toolboxes installed: ' newline warning_text newline newline 'ROI Refinement will not work without these toolboxes.'];
                msgbox(warning_text, 'Warning');
            end
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            [add_file, add_filepath] = uigetfile('*.mat', 'Choose data file to be processed.', 'MultiSelect', 'off');
            figure(app.EZROIRefinementUIFigure) % workaround to keep focus at UI window
            
            if add_file == 0
                return
            end
            
            full_filepath = [add_filepath add_file];
            app.roidata = load(full_filepath);
            app.roidata.path = full_filepath;
            
            if ~isfield(app.roidata, 'F_raw') % check to make sure data are compatible and raw data available
                warning_text = 'No ROI data found in the selected file. Did you forget to run ROI Detection first?';
                msgbox(warning_text, 'Warning');
                return
            end
            
            app.roidata.map_size = size(app.roidata.Cn);
            app.roidata.num_of_roi = size(app.roidata.F_raw, 1);
            app.roidata.num_of_frames = size(app.roidata.F_raw, 2);
            
            app.ROIListBox.Items = cellstr(string(1:app.roidata.num_of_roi)); % populate the list of ROI names
            
            calc_roidata(app)
            
            % pre-processing loaded data for displaying images
            nA = full(sqrt(sum(app.roidata.spatial_comp .^ 2))');
            [K, ~] = size(app.roidata.F_raw);
            app.roidata.spatial_comp = app.roidata.spatial_comp / spdiags(nA, 0, K, K);
            
            app.roidata.sx = min([app.roidata.options.sx, floor(app.roidata.map_size(1) / 2), floor(app.roidata.map_size(2) / 2)]);
            app.roidata.int_x = zeros(app.roidata.num_of_roi, 2 * app.roidata.sx);
            app.roidata.int_y = zeros(app.roidata.num_of_roi, 2 * app.roidata.sx);
            app.roidata.cm = com(app.roidata.spatial_comp, app.roidata.map_size(1), app.roidata.map_size(2));
            
            % generate the big ROI map with magenta outlines
            whole_field_size = round(getpixelposition(app.WholeFieldUIAxes));
            figure('visible', 'off');imagesc(imresize(app.roidata.Cn, [whole_field_size(3), whole_field_size(4)], 'nearest'));hold on
            set(gca, 'Units', 'pixels', 'Position', [0, 0, whole_field_size(3), whole_field_size(4)]);
            set(gca, 'xtick', [], 'xticklabel', [], 'ytick', [], 'yticklabel', [], 'box', 'off')
            for i = 1:app.roidata.num_of_roi
                single_ROI = full(reshape(app.roidata.spatial_comp(:, i), app.roidata.map_size(1), app.roidata.map_size(2)));
                single_ROI = imresize(single_ROI, [whole_field_size(3), whole_field_size(4)], 'nearest');
                single_ROI = medfilt2(single_ROI, [3, 3]);
                single_ROI = single_ROI(:);
                [temp, ind] = sort(single_ROI(:) .^ 2, 'ascend');
                temp =  cumsum(temp);
                ff = find(temp > (1 - 0.95) * temp(end), 1, 'first');
                if ~isempty(ff)
                    [~, ww] = contour(reshape(single_ROI, whole_field_size(3), whole_field_size(4)), [0, 0] + single_ROI(ind(ff)), 'LineColor', 'm');
                    ww.LineWidth = 1;
                end
            end
            app.roidata.ROI_map = frame2im(getframe(gca));hold off
            
            % Update stats of first ROI on GUI
            app.ROIListBox.Value = '1'; drawnow % set selection to first ROI
            view_roi(app)
        end

        % Value changed function: ROIListBox
        function ROIListBoxValueChanged(app, event)
            view_roi(app)
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            previous_roi(app)
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            next_roi(app)
        end

        % Button pushed function: IncludeROIButton
        function IncludeROIButtonPushed(app, event)
            include_roi(app)
        end

        % Button pushed function: ExcludeROIButton
        function ExcludeROIButtonPushed(app, event)
            exclude_roi(app)
        end

        % Button pushed function: SaveSettingsButton
        function SaveSettingsButtonPushed(app, event)
            [filename, filepath] = uiputfile('*.mat');
            figure(app.EZROIRefinementUIFigure) % workaround to keep focus at UI window
            
            % Check if anything was selected
            if filename == 0
                return
            end
            
            parse_roiref(app)
            roiref_settings = app.roiref;
            
            full_filename = [filepath filename];
            save(full_filename, 'roiref_settings');
        end

        % Button pushed function: LoadSettingsButton
        function LoadSettingsButtonPushed(app, event)
            [filename, filepath] = uigetfile('*.mat');
            figure(app.EZROIRefinementUIFigure) % workaround to keep focus at UI window
            
            % Check if anything was selected
            if filename == 0
                return
            end
            
            full_filename = [filepath filename];
            
            % Check if file is valid
            if ~ismember('roiref_settings', who('-file', full_filename))
                warning_text = 'The selected file is not a valid settings file.';
                msgbox(warning_text, 'Warning')
                return
            end
            
            load(full_filename, 'roiref_settings');
            app.roiref = roiref_settings;
            write_roiref(app)
        end

        % Button pushed function: RunAutoExclusionButton
        function RunAutoExclusionButtonPushed(app, event)
            if isempty(app.ROIListBox.Items)
                warning_text = 'No ROI data file has been loaded yet!';
                msgbox(warning_text, 'Warning');
                return
            end
            % Reset stop button pressed status
            app.RunAutoExclusionButton.UserData = false;
            
            % Disable run button during running
            app.RunAutoExclusionButton.Enable = 'off';
            app.StopButton.Enable = 'on';
            drawnow
            
            for cur_roi = 1:length(app.ROIListBox.Items)
                border_count = 0;
                app.ROIListBox.Value = app.ROIListBox.Items(cur_roi); drawnow
                
                % Check Stop button press
                stop_pressed = app.RunAutoExclusionButton.UserData;
                if stop_pressed
                    break
                end
                
                view_roi(app)
                
                if app.roiref.zscore_check && app.roidata.active_ROI(cur_roi) == 0
                    exclude_roi(app); continue
                end
                
                if app.roiref.baseline_stability_check
                    if app.roidata.baseline_stability(cur_roi) > app.border.baseline_stability
                        exclude_roi(app); continue
                    elseif app.roidata.baseline_stability(cur_roi) > app.roiref.baseline_stability
                        border_count = border_count + 1;
                    end
                end
                
                if app.roiref.roundness_check
                    if app.roidata.roundness(cur_roi) < app.border.roundness
                        exclude_roi(app); continue
                    elseif app.roidata.roundness(cur_roi) < app.roiref.roundness
                        border_count = border_count + 1;
                    end
                end
                
                if app.roiref.oblongness_check
                    if app.roidata.oblongness(cur_roi) > app.border.oblongness
                        exclude_roi(app); continue
                    elseif app.roidata.oblongness(cur_roi) > app.roiref.oblongness
                        border_count = border_count + 1;
                    end
                end
                
                if app.roiref.width_check
                    if app.roidata.width(cur_roi) < app.border.width_min || app.roidata.width(cur_roi) > app.border.width_max
                        exclude_roi(app); continue
                    elseif app.roidata.width(cur_roi) < app.roiref.width_min || app.roidata.width(cur_roi) > app.roiref.width_max
                        border_count = border_count + 1;
                    end
                end
                
                if app.roiref.area_check
                    if app.roidata.area(cur_roi) < app.border.area_min || app.roidata.area(cur_roi) > app.border.area_max
                        exclude_roi(app); continue
                    elseif app.roidata.area(cur_roi) < app.roiref.area_min || app.roidata.area(cur_roi) > app.roiref.area_max
                        border_count = border_count + 1;
                    end
                end
                
                if strcmp(app.roiref.skew_kurtosis_style, 'Raw')
                    if app.roiref.skewness_check
                        if app.roidata.skewness_raw(cur_roi) < app.border.skewness_min || app.roidata.skewness_raw(cur_roi) > app.border.skewness_max
                            exclude_roi(app); continue
                        elseif app.roidata.skewness_raw(cur_roi) < app.roiref.skewness_min || app.roidata.skewness_raw(cur_roi) > app.roiref.skewness_max
                            border_count = border_count + 1;
                        end
                    end
                    
                    if app.roiref.kurtosis_check
                        if app.roidata.kurtosis_raw(cur_roi) < app.border.kurtosis_min || app.roidata.kurtosis_raw(cur_roi) > app.border.kurtosis_max
                            exclude_roi(app); continue
                        elseif app.roidata.kurtosis_raw(cur_roi) < app.roiref.kurtosis_min || app.roidata.kurtosis_raw(cur_roi) > app.roiref.kurtosis_max
                            border_count = border_count + 1;
                        end
                    end
                elseif strcmp(app.roiref.skew_kurtosis_style, 'Deconvolved')
                    if app.roiref.skewness_check
                        if app.roidata.skewness_deconv(cur_roi) < app.border.skewness_min || app.roidata.skewness_deconv(cur_roi) > app.border.skewness_max
                            exclude_roi(app); continue
                        elseif app.roidata.skewness_deconv(cur_roi) < app.roiref.skewness_min || app.roidata.skewness_deconv(cur_roi) > app.roiref.skewness_max
                            border_count = border_count + 1;
                        end
                    end
                    
                    if app.roiref.kurtosis_check
                        if app.roidata.kurtosis_deconv(cur_roi) < app.border.kurtosis_min || app.roidata.kurtosis_deconv(cur_roi) > app.border.kurtosis_max
                            exclude_roi(app); continue
                        elseif app.roidata.kurtosis_deconv(cur_roi) < app.roiref.kurtosis_min || app.roidata.kurtosis_deconv(cur_roi) > app.roiref.kurtosis_max
                            border_count = border_count + 1;
                        end
                    end
                end
                
                if app.roiref.saturated_check
                    if app.roidata.saturated_frames(cur_roi) > app.border.saturated_frames
                        exclude_roi(app); continue
                    elseif app.roidata.saturated_frames(cur_roi) > app.roiref.saturated_frames
                        border_count = border_count + 1;
                    end
                end
                
                if border_count > app.roiref.borderline_allow
                    exclude_roi(app)
                elseif border_count > 0
                    app.ROIListBox.Items(cur_roi) = {[num2str(cur_roi) ' B']};
                    app.ROIListBox.Value = app.ROIListBox.Items(cur_roi);
                    drawnow
                    view_roi(app)
                else
                    include_roi(app)
                end
            end
            app.RunAutoExclusionButton.Enable = 'on';
            app.StopButton.Enable = 'off'; drawnow
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.RunAutoExclusionButton.UserData = true;
        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)
            if isempty(app.ROIListBox.Items)
                warning_text = 'No ROI data file has been loaded yet!';
                msgbox(warning_text, 'Warning');
                return
            end
            
            % disable run button during running
            app.ExportDataButton.Enable = 'off'; drawnow
            
            % Get indices of included ROIs
            app.roidata.included_ROIs = [];
            for i = 1:length(app.ROIListBox.Items)
                if ~contains(app.ROIListBox.Items(i), 'X')
                    app.roidata.included_ROIs(end + 1) = i;
                end
            end
            
            included_ROIs = app.roidata.included_ROIs;
            F_raw_refined = app.roidata.F_raw(app.roidata.included_ROIs, :);
            F_inferred_refined = app.roidata.F_inferred(app.roidata.included_ROIs, :);
            S_deconv_refined = app.roidata.S_deconv(app.roidata.included_ROIs, :);
            Z_mod = app.roidata.Z_mod;
            Z_mod_refined = app.roidata.Z_mod(app.roidata.included_ROIs, :);
            spatial_comp_refined = app.roidata.spatial_comp(:, app.roidata.included_ROIs);
            ROI_center_refined = app.roidata.ROI_center(app.roidata.included_ROIs, :);
            
            % Save settings
            filename_mat = app.roidata.path;
            roiref_settings = app.roiref;
            
            if app.MATCheckBox.Value
                if ismember('F_raw_refined', who('-file', filename_mat))
                    msgbox(['It looks like file ' filename_mat ' already have saved ROI refinement data. Will improvise a different file name to avoid overwriting.'], 'Warning')
                    filename_mat = [filename_mat(1:end-4) '_' datestr(now, 30) '.mat'];
                    save(filename_mat, 'included_ROIs', 'F_raw_refined', 'F_inferred_refined', 'S_deconv_refined', 'Z_mod', 'Z_mod_refined', 'spatial_comp_refined', 'ROI_center_refined', 'roiref_settings');
                else
                    save(filename_mat, 'included_ROIs', 'F_raw_refined', 'F_inferred_refined', 'S_deconv_refined', 'Z_mod', 'Z_mod_refined', 'spatial_comp_refined', 'ROI_center_refined', 'roiref_settings', '-append');
                end
            else
                save(filename_mat, 'roiref_settings', '-append')
            end
            
            if app.CSVCheckBox.Value
                writematrix(F_raw_refined, [filename_mat(1:end-4) '_F_raw_refined.csv'])
                writematrix(F_inferred_refined, [filename_mat(1:end-4) '_F_inferred_refined.csv'])
                writematrix(S_deconv_refined, [filename_mat(1:end-4) '_S_deconv_refined.csv'])
                writematrix(ROI_center_refined, [filename_mat(1:end-4) '_ROI_center_refined.csv'])
            end
            
            if app.XLSXCheckBox.Value
                filename_xlsx = [filename_mat(1:end-4) '.xlsx'];
                
                writematrix(F_raw_refined, filename_xlsx, 'Sheet', 'F_raw_refined')
                writematrix(F_inferred_refined, filename_xlsx, 'Sheet', 'F_inferred_refined')
                writematrix(S_deconv_refined, filename_xlsx, 'Sheet', 'S_deconv_refined')
                writematrix(ROI_center_refined, filename_xlsx, 'Sheet', 'ROI_center_refined')
            end

            if app.FLSNCheckBox.Value
                disp('FluoroSNNAP export')

                % FluoroSNNAP expects a single NxM 2D array where every value
                % is either 0 or an integer number corresponding to the
                % index of an ROI. 
                % 
                % app.roidata.spatial_comp has a separate
                % set of N*M values for each ROI, which will either be 0 or
                % the index of that ROI. This code maps those values to the
                % expected 2D array.

                % IMPORTANT: If 2+ ROIs overlap, the ROI with the highest
                % index will overwrite any other ROIs that share those
                % pixels.
                segmentation_grid = zeros(app.roidata.map_size(1));
                for i = 1:app.roidata.num_of_roi
                    single_ROI = full(reshape(app.roidata.spatial_comp(:, i), app.roidata.map_size(1), app.roidata.map_size(2)));
                    
                    %overlap_indices = find(single_ROI & segmentation_grid);
                    %if size(overlap_indices) > 0
                    %    disp(['ROI ' num2str(i) ' has ' num2str(size(overlap_indices, 1)) ' overlapping pixels']);
                    %end
    
                    %segmentation_grid(single_ROI ~= 0) = i; 

                    % Reshape and preprocess the spatial component
                    %single_ROI = imresize(single_ROI, [abs_whole_field_size(1), abs_whole_field_size(2)], 'nearest');
                    single_ROI = medfilt2(single_ROI, [3, 3]);
                    
                    % Flatten and sort squared values
                    flat_ROI = single_ROI(:);
                    [temp, ind] = sort(flat_ROI .^ 2, 'ascend');
                    temp_cumsum = cumsum(temp);
                    
                    % Find threshold index
                    ff = find(temp_cumsum > (1 - 0.95) * temp_cumsum(end), 1, 'first');
                    
                    if ~isempty(ff)
                        % Threshold value corresponding to the 95% cumulative energy
                        threshold = flat_ROI(ind(ff));
                    
                        % Generate a logical mask for values above the threshold
                        roi_mask = single_ROI >= threshold;

                        segmentation_grid(roi_mask) = i; 
                    end
                end

                % FluoroSNNAP will also merge any ROIs that are 'touching'
                % (i.e., whenever two adjacent values in the matrix have
                % different non-zero values). This code shrinks ROIs by 1 pixel when necessary to prevent this merging.
                pixels_cleaned = segmentation_grid;
                
                % Repeat until no more conflicts
                while true
                    [rows, cols] = size(pixels_cleaned);
                    conflict_mask = false(rows, cols);
                
                    % Step 1: Detect conflict pixels
                    for dx = -1:1
                        for dy = -1:1
                            if dx == 0 && dy == 0
                                continue;
                            end
                            shifted = circshift(pixels_cleaned, [dx, dy]);
                            overlap = (pixels_cleaned ~= 0) & (shifted ~= 0) & (pixels_cleaned ~= shifted);
                            conflict_mask = conflict_mask | overlap;
                        end
                    end
                
                    % Stop if no conflicts remain
                    if ~any(conflict_mask, 'all')
                        break;
                    end
                
                    % Step 2: For each label, remove conflicting boundary pixels
                    labels = unique(pixels_cleaned);
                    labels(labels == 0) = []; % Remove background
                
                    for k = 1:length(labels)
                        label = labels(k);
                        mask = (pixels_cleaned == label);
                
                        % Get perimeter of current label
                        perimeter = bwperim(mask);
                
                        % Conflict pixels on this perimeter
                        boundary_conflict = perimeter & conflict_mask;
                
                        % Remove them
                        pixels_cleaned(boundary_conflict) = 0;
                    end
                end

                filename_mat = [filename_mat(1:end-4) '_EZsegmentation.mat'];
                L = pixels_cleaned;
                save(filename_mat, 'L');
            end
            
            app.ExportDataButton.Enable = 'on'; drawnow
            
            disp('Data exported successfully.')
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            web('https://github.com/porteralab/EZcalcium/wiki/ROI-Refinement')
        end

        % Button pushed function: SettingsHelpButton
        function SettingsHelpButtonPushed(app, event)
            msgbox("The Settings section allows the user to set thresholds for a variety of criteria in order to ensure the validity of each ROI. If an ROI is outside of the set threshold for a criterion, it will be automatically excluded upon clicking Run Auto Exclusion.", "Help", 'replace')
        end

        % Button pushed function: ActivityZscoreHelpButton
        function ActivityZscoreHelpButtonPushed(app, event)
            msgbox("Activity is used to only include ROIs that surpass a chosen activity threshold for a given number of consecutive frames. This threshold can be set in the Value box in units of Z-score, and the chosen number of consecutive frames can be set in the Window box. A rule of thumb for this is above 3x Z-score for 0.5 second worth of frames.", "Help", 'replace')
        end

        % Button pushed function: BaselineLocationHelpButton
        function BaselineLocationHelpButtonPushed(app, event)
            msgbox("Baseline Location determines the percentage of the data from the beginning or the end of the recording that will be considered when determining the baseline activity level; for example, if this is set to 25%, the first 25% and the last 25% of the total frames will be used to determine the baseline values. EZcalcium will find a time window as baseline that has the minimum SD value within these frames and calculate a mean baseline value. The Window box determines the size of the baseline time window, typically set as 10 second worth of frames.", "Help", 'replace')
        end

        % Button pushed function: BaselineStabilityHelpButton
        function BaselineStabilityHelpButtonPushed(app, event)
            msgbox("Baseline Stability is used to check if an ROI has a stable baseline throughout the imaging session by comparing the baseline at the beginning of recording with the baseline at the end. It represents the absolute value of the difference between the baseline values from the beginning and end of the data.", "Help", 'replace')
        end

        % Button pushed function: RoundnessHelpButton
        function RoundnessHelpButtonPushed(app, event)
            msgbox("Roundness measures how similar an ROI is to a circle. This is useful when looking exclusively for neuron somata or other round ROIs.", "Help", 'replace')
        end

        % Button pushed function: OblongnessHelpButton
        function OblongnessHelpButtonPushed(app, event)
            msgbox("Oblongness is a measure of the ellipsoid shape of an ROI. It is calculated by dividing the length of the major axis of the ellipse by the length of the minor axis. This is useful in excluding overly oblong ROIs that are not likely to be neurons.", "Help", 'replace')
        end

        % Button pushed function: SaturatedFramesHelpButton
        function SaturatedFramesHelpButtonPushed(app, event)
            msgbox("Saturated Frames can be used to set a maximum number of frames in which the ROI can be fully saturated. This can also be input in terms of the percentage of total frames.", "Help", 'replace')
        end

        % Button pushed function: BorderlineHelpButton
        function BorderlineHelpButtonPushed(app, event)
            msgbox("Borderline can be set to allow an ROI to have criteria slightly outside of the desired range. If a criterion is outside the desired range but within the allotted borderline percentage, it will not automatically exlude the ROI. Instead, the ROI will be labeled with a ""B"", the criterion will turn yellow, and if the number of criteria that are considered Borderline is less than or equal to the number in Borderline Allowance, the ROI will be included.", "Help", 'replace')
        end

        % Button pushed function: MeanWidthHelpButton
        function MeanWidthHelpButtonPushed(app, event)
            msgbox("Mean Width measures the mean width of an ROI and allows you to select a minimum and maximum value for this parameter.", "Help", 'replace')
        end

        % Button pushed function: AreaHelpButton
        function AreaHelpButtonPushed(app, event)
            msgbox("Area allows the user to select the minimum and maximum area of an included ROI. This is useful for excluding ROIs that are too small or too large.", "Help", 'replace')
        end

        % Button pushed function: SkewnessHelpButton
        function SkewnessHelpButtonPushed(app, event)
            msgbox("Skewness is used to describe the skewness of the probability distribution of either the raw or the deconvolved data for an ROI.", "Help", 'replace')
        end

        % Button pushed function: KurtosisHelpButton
        function KurtosisHelpButtonPushed(app, event)
            msgbox("Kurtosis is used to describe the probability distribution of either the raw or the deconvolved data for an ROI. It measures the likelihood of finding outliers in a distribution. For example, a cell with large, fast, and frequent spikes in calcium levels will have a higher kurtosis value than cells with a more moderate distribution of slow activity.", "Help", 'replace')
        end

        % Button pushed function: SkewKurtosisDataHelpButton
        function SkewKurtosisDataHelpButtonPushed(app, event)
            msgbox("Skewness & Kurtosis Data is used to select whether the raw or deconvolved data will be used as the basis of the exclusion criteria for these two parameters.", "Help", 'replace')
        end

        % Button pushed function: LoadSaveHelpButton
        function LoadSaveHelpButtonPushed(app, event)
            msgbox("The Save Settings button allows the user to manually save all settings under a specific file name of one’s choosing. Settings are saved as .mat files. These include all settings in the Settings section. Note that EZcalcium will automatically save the settings into a .mat file, so manually saving the settings is usually not necessary. Load Settings allows you to load any previously saved settings. You can load the automatically generated .mat file (which is the same file that saves ROI Detection and ROI Refinement results) to check the settings used to generate the results.", "Help", 'replace')
        end

        % Button pushed function: IncludeExcludeROIHelpButton
        function IncludeExcludeROIHelpButtonPushed(app, event)
            msgbox("The Include ROI and Exclude ROI buttons allow the user to manually include or exclude an ROI regardless of the criteria in the Settings.", "Help", 'replace')
        end

        % Close request function: EZROIRefinementUIFigure
        function EZROIRefinementUIFigureCloseRequest(app, event)
            parse_roiref(app)
            roiref_settings = app.roiref;
            filepath = fileparts([mfilename('fullpath') '.m']);
            if isfile(fullfile(filepath, 'ez_settings.mat'))
                save(fullfile(filepath, 'ez_settings.mat'), 'roiref_settings', '-append')
            else
                save(fullfile(filepath, 'ez_settings.mat'), 'roiref_settings')
            end
            delete(app)
        end

        % Key press function: EZROIRefinementUIFigure
        function EZROIRefinementUIFigureKeyPress(app, event)
            switch event.Key
                case 'e'
                    if contains(app.ROIListBox.Value, 'X')
                        include_roi(app)
                    else
                        exclude_roi(app)
                    end
                case 'uparrow'
                    previous_roi(app)
                    view_roi(app)
                case 'downarrow'
                    next_roi(app)
                    view_roi(app)
            end
        end

        % Value changed function: ActivityZscoreCheckBox
        function ActivityZscoreCheckBoxValueChanged(app, event)
            if app.ActivityZscoreCheckBox.Value
                app.ActivityZscoreValueEditField.Enable = 'on';
                app.ActivityZscoreWindowEditField.Enable = 'on';
                app.FramesLabel.Enable = 'on'; drawnow
            else
                app.ActivityZscoreValueEditField.Enable = 'off';
                app.ActivityZscoreWindowEditField.Enable = 'off';
                app.FramesLabel.Enable = 'off'; drawnow
            end
            
            if ~isempty(app.ROIListBox.Items); view_roi(app); end
        end

        % Value changed function: BaselineStabilityCheckBox
        function BaselineStabilityCheckBoxValueChanged(app, event)
            if app.BaselineStabilityCheckBox.Value
                app.BaselineStabilityEditField_2.Enable = 'on'; drawnow
            else
                app.BaselineStabilityEditField_2.Enable = 'off'; drawnow
            end
            
            if ~isempty(app.ROIListBox.Items); view_roi(app); end
        end

        % Value changed function: RoundnessCheckBox
        function RoundnessCheckBoxValueChanged(app, event)
            if app.RoundnessCheckBox.Value
                app.RoundnessEditField_2.Enable = 'on'; drawnow
            else
                app.RoundnessEditField_2.Enable = 'off'; drawnow
            end
            
            if ~isempty(app.ROIListBox.Items); view_roi(app); end
        end

        % Value changed function: OblongnessCheckBox
        function OblongnessCheckBoxValueChanged(app, event)
            if app.OblongnessCheckBox.Value
                app.OblongnessEditField_2.Enable = 'on'; drawnow
            else
                app.OblongnessEditField_2.Enable = 'off'; drawnow
            end
            
            if ~isempty(app.ROIListBox.Items); view_roi(app); end
        end

        % Value changed function: SaturatedFramesCheckBox
        function SaturatedFramesCheckBoxValueChanged(app, event)
            if app.SaturatedFramesCheckBox.Value
                app.SaturatedFramesEditField_2.Enable = 'on';
                app.SaturatedFramesDropDown.Enable = 'on'; drawnow
            else
                app.SaturatedFramesEditField_2.Enable = 'off';
                app.SaturatedFramesDropDown.Enable = 'off'; drawnow
            end
            
            if ~isempty(app.ROIListBox.Items); view_roi(app); end
        end

        % Value changed function: MeanWidthCheckBox
        function MeanWidthCheckBoxValueChanged(app, event)
            if app.MeanWidthCheckBox.Value
                app.MeanWidthMinEditField.Enable = 'on';
                app.MeanWidthMaxEditField.Enable = 'on'; drawnow
            else
                app.MeanWidthMinEditField.Enable = 'off';
                app.MeanWidthMaxEditField.Enable = 'off'; drawnow
            end
            
            if ~isempty(app.ROIListBox.Items); view_roi(app); end
        end

        % Value changed function: AreaCheckBox
        function AreaCheckBoxValueChanged(app, event)
            if app.AreaCheckBox.Value
                app.AreaMinEditField.Enable = 'on';
                app.AreaMaxEditField.Enable = 'on'; drawnow
            else
                app.AreaMinEditField.Enable = 'off';
                app.AreaMaxEditField.Enable = 'off'; drawnow
            end
            
            if ~isempty(app.ROIListBox.Items); view_roi(app); end
        end

        % Value changed function: SkewnessCheckBox
        function SkewnessCheckBoxValueChanged(app, event)
            if app.SkewnessCheckBox.Value
                app.SkewnessMinEditField.Enable = 'on';
                app.SkewnessMaxEditField.Enable = 'on'; drawnow
            else
                app.SkewnessMinEditField.Enable = 'off';
                app.SkewnessMaxEditField.Enable = 'off'; drawnow
            end
            
            if ~isempty(app.ROIListBox.Items); view_roi(app); end
        end

        % Value changed function: KurtosisCheckBox
        function KurtosisCheckBoxValueChanged(app, event)
            if app.KurtosisCheckBox.Value
                app.KurtosisMinEditField.Enable = 'on';
                app.KurtosisMaxEditField.Enable = 'on'; drawnow
            else
                app.KurtosisMinEditField.Enable = 'off';
                app.KurtosisMaxEditField.Enable = 'off'; drawnow
            end
            
            if ~isempty(app.ROIListBox.Items); view_roi(app); end
        end

        % Value changed function: ActivityZscoreValueEditField
        function ActivityZscoreValueEditFieldValueChanged(app, event)
            if ~isempty(app.ROIListBox.Items); calc_roidata(app); view_roi(app); end
        end

        % Value changed function: ActivityZscoreWindowEditField
        function ActivityZscoreWindowEditFieldValueChanged(app, event)
            if ~isempty(app.ROIListBox.Items); calc_roidata(app); view_roi(app); end
        end

        % Value changed function: BaselineLocationValueEditField
        function BaselineLocationValueEditFieldValueChanged(app, event)
            if ~isempty(app.ROIListBox.Items); calc_roidata(app); view_roi(app); end
        end

        % Value changed function: BaselineLocationWindowEditField
        function BaselineLocationWindowEditFieldValueChanged(app, event)
            if ~isempty(app.ROIListBox.Items); calc_roidata(app); view_roi(app); end
        end

        % Value changed function: BaselineStabilityEditField_2
        function BaselineStabilityEditField_2ValueChanged(app, event)
            if ~isempty(app.ROIListBox.Items); view_roi(app); end
        end

        % Value changed function: RoundnessEditField_2
        function RoundnessEditField_2ValueChanged(app, event)
            if ~isempty(app.ROIListBox.Items); view_roi(app); end
        end

        % Value changed function: OblongnessEditField_2
        function OblongnessEditField_2ValueChanged(app, event)
            if ~isempty(app.ROIListBox.Items); view_roi(app); end
        end

        % Value changed function: SaturatedFramesEditField_2
        function SaturatedFramesEditField_2ValueChanged(app, event)
            if ~isempty(app.ROIListBox.Items); view_roi(app); end
        end

        % Value changed function: SaturatedFramesDropDown
        function SaturatedFramesDropDownValueChanged(app, event)
            if ~isempty(app.ROIListBox.Items); view_roi(app); end
        end

        % Value changed function: BorderlineEditField
        function BorderlineEditFieldValueChanged(app, event)
            if ~isempty(app.ROIListBox.Items); view_roi(app); end
        end

        % Value changed function: BorderlineAllowanceEditField
        function BorderlineAllowanceEditFieldValueChanged(app, event)
            if ~isempty(app.ROIListBox.Items); view_roi(app); end
        end

        % Value changed function: MeanWidthMinEditField
        function MeanWidthMinEditFieldValueChanged(app, event)
            if ~isempty(app.ROIListBox.Items); view_roi(app); end
        end

        % Value changed function: MeanWidthMaxEditField
        function MeanWidthMaxEditFieldValueChanged(app, event)
            if ~isempty(app.ROIListBox.Items); view_roi(app); end
        end

        % Value changed function: AreaMinEditField
        function AreaMinEditFieldValueChanged(app, event)
            if ~isempty(app.ROIListBox.Items); view_roi(app); end
        end

        % Value changed function: AreaMaxEditField
        function AreaMaxEditFieldValueChanged(app, event)
            if ~isempty(app.ROIListBox.Items); view_roi(app); end
        end

        % Value changed function: SkewnessMinEditField
        function SkewnessMinEditFieldValueChanged(app, event)
            if ~isempty(app.ROIListBox.Items); view_roi(app); end
        end

        % Value changed function: SkewnessMaxEditField
        function SkewnessMaxEditFieldValueChanged(app, event)
            if ~isempty(app.ROIListBox.Items); view_roi(app); end
        end

        % Value changed function: KurtosisMinEditField
        function KurtosisMinEditFieldValueChanged(app, event)
            if ~isempty(app.ROIListBox.Items); view_roi(app); end
        end

        % Value changed function: KurtosisMaxEditField
        function KurtosisMaxEditFieldValueChanged(app, event)
            if ~isempty(app.ROIListBox.Items); view_roi(app); end
        end

        % Value changed function: SkewKurtosisDataDropDown
        function SkewKurtosisDataDropDownValueChanged(app, event)
            if ~isempty(app.ROIListBox.Items); view_roi(app); end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EZROIRefinementUIFigure = uifigure('Visible', 'off');
            app.EZROIRefinementUIFigure.Name = 'EZ ROI Refinement';
            app.EZROIRefinementUIFigure.CloseRequestFcn = createCallbackFcn(app, @EZROIRefinementUIFigureCloseRequest, true);
            app.EZROIRefinementUIFigure.KeyPressFcn = createCallbackFcn(app, @EZROIRefinementUIFigureKeyPress, true);
            app.ROIRefinementLabel = uilabel(app.EZROIRefinementUIFigure);
            app.ROIRefinementLabel.Text = 'ROI Refinement';
            app.SettingsLabel = uilabel(app.EZROIRefinementUIFigure);
            app.SettingsLabel.Text = 'Settings';
            app.ROIsLabel = uilabel(app.EZROIRefinementUIFigure);
            app.ROIsLabel.Text = 'ROIs';
            app.ROIsPanel = uipanel(app.EZROIRefinementUIFigure);
            app.LoadDataButton = uibutton(app.ROIsPanel, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.ROIListBox = uilistbox(app.ROIsPanel);
            app.ROIListBox.Items = {};
            app.ROIListBox.ValueChangedFcn = createCallbackFcn(app, @ROIListBoxValueChanged, true);
            app.ROIListBox.Value = {};
            app.PreviousButton = uibutton(app.ROIsPanel, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = '▲';
            app.NextButton = uibutton(app.ROIsPanel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = '▼';
            app.InfoPanel = uipanel(app.EZROIRefinementUIFigure);
            app.WholeFieldUIAxes = uiaxes(app.InfoPanel);
            app.WholeFieldUIAxes.XTick = [];
            app.WholeFieldUIAxes.YTick = [];
            app.SingleROIUIAxes = uiaxes(app.InfoPanel);
            app.SingleROIUIAxes.XTick = [];
            app.SingleROIUIAxes.YTick = [];
            app.RawUIAxes = uiaxes(app.InfoPanel);
            app.RawUIAxes.XTick = [];
            app.RawUIAxes.YTick = [];
            app.InferredUIAxes = uiaxes(app.InfoPanel);
            app.InferredUIAxes.XTick = [];
            app.InferredUIAxes.YTick = [];
            app.DeconvolvedUIAxes = uiaxes(app.InfoPanel);
            app.DeconvolvedUIAxes.XTick = [];
            app.DeconvolvedUIAxes.YTick = [];
            app.BaselineStabilityLabel = uilabel(app.InfoPanel);
            app.BaselineStabilityLabel.Text = 'Baseline Stability';
            app.RoundnessLabel = uilabel(app.InfoPanel);
            app.RoundnessLabel.Text = 'Roundness';
            app.OblongnessLabel = uilabel(app.InfoPanel);
            app.OblongnessLabel.Text = 'Oblongness';
            app.MeanWidthLabel = uilabel(app.InfoPanel);
            app.MeanWidthLabel.Text = 'Mean Width';
            app.AreaLabel = uilabel(app.InfoPanel);
            app.AreaLabel.Text = 'Area';
            app.SkewnessRawLabel = uilabel(app.InfoPanel);
            app.SkewnessRawLabel.Text = 'Skewness (Raw)';
            app.SkewnessDeconvLabel = uilabel(app.InfoPanel);
            app.SkewnessDeconvLabel.Text = 'Skewness (Deconv.)';
            app.KurtosisRawLabel = uilabel(app.InfoPanel);
            app.KurtosisRawLabel.Text = 'Kurtosis (Raw)';
            app.KurtosisDeconvLabel = uilabel(app.InfoPanel);
            app.KurtosisDeconvLabel.Text = 'Kurtosis (Deconv.)';
            app.SaturatedFramesLabel = uilabel(app.InfoPanel);
            app.SaturatedFramesLabel.Text = 'Saturated Frames';
            app.IncludeROIButton = uibutton(app.InfoPanel, 'push');
            app.IncludeROIButton.ButtonPushedFcn = createCallbackFcn(app, @IncludeROIButtonPushed, true);
            app.IncludeROIButton.Text = 'Include ROI';
            app.ExcludeROIButton = uibutton(app.InfoPanel, 'push');
            app.ExcludeROIButton.ButtonPushedFcn = createCallbackFcn(app, @ExcludeROIButtonPushed, true);
            app.ExcludeROIButton.Text = 'Exclude ROI';
            app.IncludeExcludeROIHelpButton = uibutton(app.InfoPanel, 'push');
            app.IncludeExcludeROIHelpButton.ButtonPushedFcn = createCallbackFcn(app, @IncludeExcludeROIHelpButtonPushed, true);
            app.IncludeExcludeROIHelpButton.Text = '?';
            app.RawLabel = uilabel(app.InfoPanel);
            app.RawLabel.Text = 'Raw';
            app.InferredLabel = uilabel(app.InfoPanel);
            app.InferredLabel.Text = 'Inferred';
            app.DeconvolvedLabel = uilabel(app.InfoPanel);
            app.DeconvolvedLabel.Text = 'Deconvolved';
            app.StatusEditField = uieditfield(app.InfoPanel, 'text');
            app.StatusEditField.Editable = 'off';
            app.BaselineStabilityEditField = uieditfield(app.InfoPanel, 'text');
            app.BaselineStabilityEditField.Editable = 'off';
            app.RoundnessEditField = uieditfield(app.InfoPanel, 'text');
            app.RoundnessEditField.Editable = 'off';
            app.OblongnessEditField = uieditfield(app.InfoPanel, 'text');
            app.OblongnessEditField.Editable = 'off';
            app.MeanWidthEditField = uieditfield(app.InfoPanel, 'text');
            app.MeanWidthEditField.Editable = 'off';
            app.AreaEditField = uieditfield(app.InfoPanel, 'text');
            app.AreaEditField.Editable = 'off';
            app.SkewnessRawEditField = uieditfield(app.InfoPanel, 'text');
            app.SkewnessRawEditField.Editable = 'off';
            app.SkewnessDeconvEditField = uieditfield(app.InfoPanel, 'text');
            app.SkewnessDeconvEditField.Editable = 'off';
            app.KurtosisRawEditField = uieditfield(app.InfoPanel, 'text');
            app.KurtosisRawEditField.Editable = 'off';
            app.KurtosisDeconvEditField = uieditfield(app.InfoPanel, 'text');
            app.KurtosisDeconvEditField.Editable = 'off';
            app.SaturatedFramesEditField = uieditfield(app.InfoPanel, 'text');
            app.SaturatedFramesEditField.Editable = 'off';
            app.SettingsPanel = uipanel(app.EZROIRefinementUIFigure);
            app.SaveSettingsButton = uibutton(app.SettingsPanel, 'push');
            app.SaveSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSettingsButtonPushed, true);
            app.SaveSettingsButton.Text = 'Save Settings';
            app.LoadSettingsButton = uibutton(app.SettingsPanel, 'push');
            app.LoadSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSettingsButtonPushed, true);
            app.LoadSettingsButton.Text = 'Load Settings';
            app.LoadSaveHelpButton = uibutton(app.SettingsPanel, 'push');
            app.LoadSaveHelpButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSaveHelpButtonPushed, true);
            app.LoadSaveHelpButton.Text = '?';
            app.ActivityZscoreValueEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.ActivityZscoreValueEditField.ValueChangedFcn = createCallbackFcn(app, @ActivityZscoreValueEditFieldValueChanged, true);
            app.ActivityZscoreValueEditField.Value = 3;
            app.BaselineLocationLabel = uilabel(app.SettingsPanel);
            app.BaselineLocationLabel.Text = 'Baseline Location (%)';
            app.BorderlineAllowanceLabel = uilabel(app.SettingsPanel);
            app.BorderlineAllowanceLabel.Text = 'Borderline Allowance';
            app.ExportFormatLabel = uilabel(app.SettingsPanel);
            app.ExportFormatLabel.Text = 'Export Format';
            app.MATCheckBox = uicheckbox(app.SettingsPanel);
            app.MATCheckBox.Text = 'MAT';
            app.MATCheckBox.Value = true;
            app.CSVCheckBox = uicheckbox(app.SettingsPanel);
            app.CSVCheckBox.Text = 'CSV';
            app.XLSXCheckBox = uicheckbox(app.SettingsPanel);
            app.XLSXCheckBox.Text = 'XLSX';
            app.BaselineLocationValueEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.BaselineLocationValueEditField.ValueChangedFcn = createCallbackFcn(app, @BaselineLocationValueEditFieldValueChanged, true);
            app.BaselineLocationValueEditField.Value = 25;
            app.BaselineStabilityEditField_2 = uieditfield(app.SettingsPanel, 'numeric');
            app.BaselineStabilityEditField_2.ValueChangedFcn = createCallbackFcn(app, @BaselineStabilityEditField_2ValueChanged, true);
            app.BaselineStabilityEditField_2.Value = 10;
            app.RoundnessEditField_2 = uieditfield(app.SettingsPanel, 'numeric');
            app.RoundnessEditField_2.ValueChangedFcn = createCallbackFcn(app, @RoundnessEditField_2ValueChanged, true);
            app.RoundnessEditField_2.Value = 0.5;
            app.OblongnessEditField_2 = uieditfield(app.SettingsPanel, 'numeric');
            app.OblongnessEditField_2.ValueChangedFcn = createCallbackFcn(app, @OblongnessEditField_2ValueChanged, true);
            app.OblongnessEditField_2.Value = 2;
            app.BorderlineEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.BorderlineEditField.ValueChangedFcn = createCallbackFcn(app, @BorderlineEditFieldValueChanged, true);
            app.BorderlineEditField.Value = 10;
            app.BorderlineAllowanceEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.BorderlineAllowanceEditField.ValueChangedFcn = createCallbackFcn(app, @BorderlineAllowanceEditFieldValueChanged, true);
            app.BorderlineAllowanceEditField.Value = 1;
            app.MeanWidthMinEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.MeanWidthMinEditField.ValueChangedFcn = createCallbackFcn(app, @MeanWidthMinEditFieldValueChanged, true);
            app.MeanWidthMinEditField.Value = 5;
            app.AreaMinEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.AreaMinEditField.ValueChangedFcn = createCallbackFcn(app, @AreaMinEditFieldValueChanged, true);
            app.AreaMinEditField.Value = 100;
            app.SkewnessMaxEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.SkewnessMaxEditField.ValueChangedFcn = createCallbackFcn(app, @SkewnessMaxEditFieldValueChanged, true);
            app.SkewnessMaxEditField.Value = 100;
            app.KurtosisMinEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.KurtosisMinEditField.ValueChangedFcn = createCallbackFcn(app, @KurtosisMinEditFieldValueChanged, true);
            app.MinLabel = uilabel(app.SettingsPanel);
            app.MinLabel.Text = 'Min';
            app.SkewKurtosisDataLabel = uilabel(app.SettingsPanel);
            app.SkewKurtosisDataLabel.Text = 'Skew. & Kurtosis Data';
            app.SkewKurtosisDataDropDown = uidropdown(app.SettingsPanel);
            app.SkewKurtosisDataDropDown.Items = {'Raw', 'Deconvolved'};
            app.SkewKurtosisDataDropDown.ValueChangedFcn = createCallbackFcn(app, @SkewKurtosisDataDropDownValueChanged, true);
            app.SkewKurtosisDataDropDown.Value = 'Raw';
            app.KurtosisMaxEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.KurtosisMaxEditField.ValueChangedFcn = createCallbackFcn(app, @KurtosisMaxEditFieldValueChanged, true);
            app.KurtosisMaxEditField.Value = 100;
            app.SkewnessMinEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.SkewnessMinEditField.ValueChangedFcn = createCallbackFcn(app, @SkewnessMinEditFieldValueChanged, true);
            app.AreaMaxEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.AreaMaxEditField.ValueChangedFcn = createCallbackFcn(app, @AreaMaxEditFieldValueChanged, true);
            app.AreaMaxEditField.Value = 1000;
            app.MeanWidthMaxEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.MeanWidthMaxEditField.ValueChangedFcn = createCallbackFcn(app, @MeanWidthMaxEditFieldValueChanged, true);
            app.MeanWidthMaxEditField.Value = 50;
            app.MaxLabel = uilabel(app.SettingsPanel);
            app.MaxLabel.Text = 'Max';
            app.BorderlineLabel = uilabel(app.SettingsPanel);
            app.BorderlineLabel.Text = 'Borderline (%)';
            app.SaturatedFramesEditField_2 = uieditfield(app.SettingsPanel, 'numeric');
            app.SaturatedFramesEditField_2.ValueChangedFcn = createCallbackFcn(app, @SaturatedFramesEditField_2ValueChanged, true);
            app.SaturatedFramesDropDown = uidropdown(app.SettingsPanel);
            app.SaturatedFramesDropDown.Items = {'Frames', '%'};
            app.SaturatedFramesDropDown.ValueChangedFcn = createCallbackFcn(app, @SaturatedFramesDropDownValueChanged, true);
            app.SaturatedFramesDropDown.Value = 'Frames';
            app.BaselineLocationWindowEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.BaselineLocationWindowEditField.ValueChangedFcn = createCallbackFcn(app, @BaselineLocationWindowEditFieldValueChanged, true);
            app.BaselineLocationWindowEditField.Value = 100;
            app.ActivityZscoreWindowEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.ActivityZscoreWindowEditField.ValueChangedFcn = createCallbackFcn(app, @ActivityZscoreWindowEditFieldValueChanged, true);
            app.ActivityZscoreWindowEditField.Value = 5;
            app.ValueLabel = uilabel(app.SettingsPanel);
            app.ValueLabel.Text = 'Value';
            app.WindowLabel = uilabel(app.SettingsPanel);
            app.WindowLabel.Text = 'Window';
            app.ActivityZscoreHelpButton = uibutton(app.SettingsPanel, 'push');
            app.ActivityZscoreHelpButton.ButtonPushedFcn = createCallbackFcn(app, @ActivityZscoreHelpButtonPushed, true);
            app.ActivityZscoreHelpButton.Text = '?';
            app.BaselineLocationHelpButton = uibutton(app.SettingsPanel, 'push');
            app.BaselineLocationHelpButton.ButtonPushedFcn = createCallbackFcn(app, @BaselineLocationHelpButtonPushed, true);
            app.BaselineLocationHelpButton.Text = '?';
            app.BaselineStabilityHelpButton = uibutton(app.SettingsPanel, 'push');
            app.BaselineStabilityHelpButton.ButtonPushedFcn = createCallbackFcn(app, @BaselineStabilityHelpButtonPushed, true);
            app.BaselineStabilityHelpButton.Text = '?';
            app.RoundnessHelpButton = uibutton(app.SettingsPanel, 'push');
            app.RoundnessHelpButton.ButtonPushedFcn = createCallbackFcn(app, @RoundnessHelpButtonPushed, true);
            app.RoundnessHelpButton.Text = '?';
            app.OblongnessHelpButton = uibutton(app.SettingsPanel, 'push');
            app.OblongnessHelpButton.ButtonPushedFcn = createCallbackFcn(app, @OblongnessHelpButtonPushed, true);
            app.OblongnessHelpButton.Text = '?';
            app.SaturatedFramesHelpButton = uibutton(app.SettingsPanel, 'push');
            app.SaturatedFramesHelpButton.ButtonPushedFcn = createCallbackFcn(app, @SaturatedFramesHelpButtonPushed, true);
            app.SaturatedFramesHelpButton.Text = '?';
            app.BorderlineHelpButton = uibutton(app.SettingsPanel, 'push');
            app.BorderlineHelpButton.ButtonPushedFcn = createCallbackFcn(app, @BorderlineHelpButtonPushed, true);
            app.BorderlineHelpButton.Text = '?';
            app.BorderlineAllowanceHelpButton = uibutton(app.SettingsPanel, 'push');
            app.BorderlineAllowanceHelpButton.Text = '?';
            app.MeanWidthHelpButton = uibutton(app.SettingsPanel, 'push');
            app.MeanWidthHelpButton.ButtonPushedFcn = createCallbackFcn(app, @MeanWidthHelpButtonPushed, true);
            app.MeanWidthHelpButton.Text = '?';
            app.AreaHelpButton = uibutton(app.SettingsPanel, 'push');
            app.AreaHelpButton.ButtonPushedFcn = createCallbackFcn(app, @AreaHelpButtonPushed, true);
            app.AreaHelpButton.Text = '?';
            app.SkewnessHelpButton = uibutton(app.SettingsPanel, 'push');
            app.SkewnessHelpButton.ButtonPushedFcn = createCallbackFcn(app, @SkewnessHelpButtonPushed, true);
            app.SkewnessHelpButton.Text = '?';
            app.KurtosisHelpButton = uibutton(app.SettingsPanel, 'push');
            app.KurtosisHelpButton.ButtonPushedFcn = createCallbackFcn(app, @KurtosisHelpButtonPushed, true);
            app.KurtosisHelpButton.Text = '?';
            app.SkewKurtosisDataHelpButton = uibutton(app.SettingsPanel, 'push');
            app.SkewKurtosisDataHelpButton.ButtonPushedFcn = createCallbackFcn(app, @SkewKurtosisDataHelpButtonPushed, true);
            app.SkewKurtosisDataHelpButton.Text = '?';
            app.FramesLabel = uilabel(app.SettingsPanel);
            app.FramesLabel.Text = 'Frames';
            app.FramesLabel_2 = uilabel(app.SettingsPanel);
            app.FramesLabel_2.Text = 'Frames';
            app.ActivityZscoreCheckBox = uicheckbox(app.SettingsPanel);
            app.ActivityZscoreCheckBox.ValueChangedFcn = createCallbackFcn(app, @ActivityZscoreCheckBoxValueChanged, true);
            app.ActivityZscoreCheckBox.Text = 'Activity (Z-score)';
            app.BaselineStabilityCheckBox = uicheckbox(app.SettingsPanel);
            app.BaselineStabilityCheckBox.ValueChangedFcn = createCallbackFcn(app, @BaselineStabilityCheckBoxValueChanged, true);
            app.BaselineStabilityCheckBox.Text = 'Baseline Stability';
            app.RoundnessCheckBox = uicheckbox(app.SettingsPanel);
            app.RoundnessCheckBox.ValueChangedFcn = createCallbackFcn(app, @RoundnessCheckBoxValueChanged, true);
            app.RoundnessCheckBox.Text = 'Roundness';
            app.RoundnessCheckBox.Value = true;
            app.OblongnessCheckBox = uicheckbox(app.SettingsPanel);
            app.OblongnessCheckBox.ValueChangedFcn = createCallbackFcn(app, @OblongnessCheckBoxValueChanged, true);
            app.OblongnessCheckBox.Text = 'Oblongness';
            app.OblongnessCheckBox.Value = true;
            app.SaturatedFramesCheckBox = uicheckbox(app.SettingsPanel);
            app.SaturatedFramesCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaturatedFramesCheckBoxValueChanged, true);
            app.SaturatedFramesCheckBox.Text = 'Saturated Frames';
            app.MeanWidthCheckBox = uicheckbox(app.SettingsPanel);
            app.MeanWidthCheckBox.ValueChangedFcn = createCallbackFcn(app, @MeanWidthCheckBoxValueChanged, true);
            app.MeanWidthCheckBox.Text = 'Mean Width';
            app.MeanWidthCheckBox.Value = true;
            app.AreaCheckBox = uicheckbox(app.SettingsPanel);
            app.AreaCheckBox.ValueChangedFcn = createCallbackFcn(app, @AreaCheckBoxValueChanged, true);
            app.AreaCheckBox.Text = 'Area';
            app.AreaCheckBox.Value = true;
            app.SkewnessCheckBox = uicheckbox(app.SettingsPanel);
            app.SkewnessCheckBox.ValueChangedFcn = createCallbackFcn(app, @SkewnessCheckBoxValueChanged, true);
            app.SkewnessCheckBox.Text = 'Skewness';
            app.KurtosisCheckBox = uicheckbox(app.SettingsPanel);
            app.KurtosisCheckBox.ValueChangedFcn = createCallbackFcn(app, @KurtosisCheckBoxValueChanged, true);
            app.KurtosisCheckBox.Text = 'Kurtosis';
            app.FLSNCheckBox = uicheckbox(app.SettingsPanel);
            app.FLSNCheckBox.Text = 'FLSN';
            app.RunAutoExclusionButton = uibutton(app.EZROIRefinementUIFigure, 'push');
            app.RunAutoExclusionButton.ButtonPushedFcn = createCallbackFcn(app, @RunAutoExclusionButtonPushed, true);
            app.RunAutoExclusionButton.Text = 'Run Auto Exclusion';
            app.HelpButton = uibutton(app.EZROIRefinementUIFigure, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            app.ExportDataButton = uibutton(app.EZROIRefinementUIFigure, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Text = 'Export Data';
            app.SettingsHelpButton = uibutton(app.EZROIRefinementUIFigure, 'push');
            app.SettingsHelpButton.ButtonPushedFcn = createCallbackFcn(app, @SettingsHelpButtonPushed, true);
            app.SettingsHelpButton.Text = '?';
            app.StopButton = uibutton(app.EZROIRefinementUIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            % Show the figure after all components are created
            app.EZROIRefinementUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ez_roi_refine

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.EZROIRefinementUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.EZROIRefinementUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EZROIRefinementUIFigure)
        end
    end
end